classdef TrabalhoFinal_Visao < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        ListBoxImages       matlab.ui.control.ListBox
        UIAxesImage         matlab.ui.control.UIAxes
        ButtonImage         matlab.ui.control.Button
        LabelImage          matlab.ui.control.Label
        ButtonGroup         matlab.ui.container.ButtonGroup
        FolderAButton       matlab.ui.control.RadioButton
        FolderBButton       matlab.ui.control.RadioButton
        UIAxes              matlab.ui.control.UIAxes
        DetectforallButton  matlab.ui.control.Button
        ResultsLabel        matlab.ui.control.Label
        DetectdiseaseinlemonwithVisionComputerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        ImagesFolderPath = 'dataset/all_short'
        ImageFiles = {};
        img = '';
    end
    
    methods (Access = private)
        
        function loadImages(app)
            % Carregar as imagens ".jpg" da pasta informada
            if isfolder(app.ImagesFolderPath)
                imageFiles = dir(fullfile(app.ImagesFolderPath, '*.jpg'));
                app.ImageFiles = {imageFiles.name};
                app.ListBoxImages.Items = app.ImageFiles;

                % Messagem para tratar se as imagens foram carregadas
                disp('Imagens carregadas:');
                disp(app.ImageFiles);
            else
                disp('Pasta de imagens não encontrada.');
            end
        end
        
        function displaySelectedImage(app)
            selectedImage = app.ListBoxImages.Value;
            if ~isempty(selectedImage)
                fullFilePath = fullfile(app.ImagesFolderPath, selectedImage);
                
                % Ler e exibir a imagem
                app.img = imread(fullFilePath);
                imshow(app.img, 'Parent', app.UIAxesImage);
            end
        end
        
        function processImageFunction(app)
            % Processar a imagem
            resultText = processImage(app, app.img);
            app.LabelImage.Text = resultText;
        end
        
        function resultText = processImage(app, img)
            % Redimensionar a imagem
            img = imresize(img, [256, 256]);

            % Converter para escala de cinza
            grayImg = rgb2gray(img);

            % Ajustar contraste
            imgAdjusted = imadjust(grayImg);
            
            % Filtragem para suavizar a imagem
            imgFiltered = imgaussfilt(imgAdjusted, 2);

            % Detectar bordas para separar objetos do fundo
            edges = edge(imgFiltered, 'Canny');

            % Operações morfológicas para melhorar a separação
            se = strel('disk', 5);
            edges = imdilate(edges, se);
            edges = imfill(edges, 'holes');
            edges = imerode(edges, se);
            edges = bwareaopen(edges, 500);

            % Análise de propriedades da região
            stats = regionprops(edges, 'Area', 'BoundingBox', 'Eccentricity', 'Extent', 'Solidity');

            % Verificar se há regiões detectadas
            if isempty(stats)
                resultText = 'Sem limão na imagem.';
                return;
            end

            % Avaliar as propriedades das regiões para identificar limões
            areas = [stats.Area];
            [~, idx] = max(areas);
            mainRegion = stats(idx);

            % Verificar se a região principal é um limão
            if mainRegion.Extent < 0.6 || mainRegion.Solidity < 0.8
                resultText = 'Limão estragado detectado.';
                bbox = mainRegion.BoundingBox;
                rectangle('Position', bbox, 'EdgeColor', 'r', 'LineWidth', 2, 'Parent', app.UIAxesImage);
            else
                resultText = 'Limão de boa qualidade.';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadImages(app);
        end

        % Value changed function: ListBoxImages
        function ListBoxImagesValueChanged(app, event)
            displaySelectedImage(app);
        end

        % Button pushed function: ButtonImage
        function ButtonImagePushed(app, event)
            processImageFunction(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBoxImages = uilistbox(app.UIFigure);
            app.ListBoxImages.ValueChangedFcn = createCallbackFcn(app, @ListBoxImagesValueChanged, true);
            app.UIAxesImage = uiaxes(app.UIFigure);
            title(app.UIAxesImage, 'Original Image')
            xlabel(app.UIAxesImage, 'X')
            ylabel(app.UIAxesImage, 'Y')
            app.ButtonImage = uibutton(app.UIFigure, 'push');
            app.ButtonImage.ButtonPushedFcn = createCallbackFcn(app, @ButtonImagePushed, true);
            app.ButtonImage.Text = 'Detect';
            app.LabelImage = uilabel(app.UIFigure);
            app.LabelImage.Text = '';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.FolderAButton = uiradiobutton(app.ButtonGroup);
            app.FolderAButton.Text = 'Folder A';
            app.FolderAButton.Value = true;
            app.FolderBButton = uiradiobutton(app.ButtonGroup);
            app.FolderBButton.Text = 'Folder B';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DetectforallButton = uibutton(app.UIFigure, 'push');
            app.DetectforallButton.Text = 'Detect for all';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results:';
            app.DetectdiseaseinlemonwithVisionComputerLabel = uilabel(app.UIFigure);
            app.DetectdiseaseinlemonwithVisionComputerLabel.Text = 'Detect disease in lemon with Vision Computer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TrabalhoFinal_Visao

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end